classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Button                   matlab.ui.control.StateButton
        TabGroup                 matlab.ui.container.TabGroup
        LabBotTab                matlab.ui.container.Tab
        EditField_LabBotJoint1   matlab.ui.control.NumericEditField
        Joint1Slider_LabBot      matlab.ui.control.Slider
        EditField__LabBotJoint2  matlab.ui.control.NumericEditField
        Joint2Slider_LabBot      matlab.ui.control.Slider
        EditField__LabBotJoint3  matlab.ui.control.NumericEditField
        Joint3Slider_LabBot      matlab.ui.control.Slider
        EditField__LabBotJoint4  matlab.ui.control.NumericEditField
        Joint4Slider_LabBot      matlab.ui.control.Slider
        EditField__LabBotJoint5  matlab.ui.control.NumericEditField
        Joint5Slider_LabBot      matlab.ui.control.Slider
        EditField__LabBotJoint6  matlab.ui.control.NumericEditField
        Joint6Slider_LabBot      matlab.ui.control.Slider
        EditField__LabBotJoint7  matlab.ui.control.NumericEditField
        Joint7Slider_LabBot      matlab.ui.control.Slider
        Joint7SliderLabel        matlab.ui.control.Label
        Joint6SliderLabel        matlab.ui.control.Label
        Joint4SliderLabel        matlab.ui.control.Label
        Joint5SliderLabel        matlab.ui.control.Label
        Joint3SliderLabel        matlab.ui.control.Label
        Joint2SliderLabel        matlab.ui.control.Label
        Joint1SliderLabel        matlab.ui.control.Label
        UR3Tab                   matlab.ui.container.Tab
        Joint6Slider_UR3         matlab.ui.control.Slider
        Joint6SliderLabel_2      matlab.ui.control.Label
        Joint4Slider_UR3         matlab.ui.control.Slider
        Joint4SliderLabel_2      matlab.ui.control.Label
        Joint5Slider_UR3         matlab.ui.control.Slider
        Joint5SliderLabel_2      matlab.ui.control.Label
        Joint3Slider_UR3         matlab.ui.control.Slider
        Joint3SliderLabel_2      matlab.ui.control.Label
        Joint2Slider_UR3         matlab.ui.control.Slider
        Joint2SliderLabel_2      matlab.ui.control.Label
        Joint1Slider_UR3         matlab.ui.control.Slider
        Joint1SliderLabel_2      matlab.ui.control.Label
        EditField__UR3Joint5     matlab.ui.control.NumericEditField
        EditField__UR3Joint6     matlab.ui.control.NumericEditField
        EditField__UR3Joint4     matlab.ui.control.NumericEditField
        EditField__UR3Joint3     matlab.ui.control.NumericEditField
        EditField__UR3Joint2     matlab.ui.control.NumericEditField
        EditField_UR3Joint1      matlab.ui.control.NumericEditField
        MomentaryButton          slrealtime.ui.control.MomentaryButton
        Switch                   matlab.ui.control.RockerSwitch
        SwitchLabel              matlab.ui.control.Label
        ArmBStatusLamp           matlab.ui.control.Lamp
        ArmBStatusLampLabel      matlab.ui.control.Label
        ArmAStatusLamp           matlab.ui.control.Lamp
        ArmAStatusLampLabel      matlab.ui.control.Label
        ChemicalsListBox         matlab.ui.control.ListBox
        ChemicalsListBoxLabel    matlab.ui.control.Label
    end

    
    properties (Access = public)
        GUI_Func                  % GUI functions object
        autoUpdate
        myTimer % Description
        
        UR3J1
        UR3J2
        UR3J3
        UR3J4
        UR3J5
        UR3J6

        LabBotJ1
        LabBotJ2
        LabBotJ3
        LabBotJ4
        LabBotJ5
        LabBotJ6
        LabBotJ7      
    end
   
    methods (Access = private)
        
        function updateJoy(app,~,~)
            wrench = zeros(6,1);
            wrench(1) = app.UR3J1; 
            wrench(2) = app.UR3J2;
            wrench(3) = app.UR3J3;
            wrench(4) = app.UR3J4;
            wrench(5) = app.UR3J5;
            wrench(6) = app.UR3J6;
            
            assignin('base','wrench',wrench);
            %evalin('base','who');
            %wrench = [app.vx; app.vy; app.vz; app.wx; app.wy; app.wz];
            %disp(wrench);
        end


    end
    
    methods (Access = public)
        
        function wrench = read(app)
            wrench = zeros(6,1);
            wrench(1) = app.UR3J1; 
            wrench(2) = app.UR3J2;
            wrench(3) = app.UR3J3;
            wrench(4) = app.UR3J4;
            wrench(5) = app.UR3J5;
            wrench(6) = app.UR3J6;
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UR3J1 = 0;
            app.UR3J2 = 0;
            app.UR3J3 = 0;
            app.UR3J4 = 0;
            app.UR3J5 = 0;
            app.UR3J6 = 0;
             
            app.myTimer = timer('Period',0.05,...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf);

            app.myTimer.TimerFcn = @(~,~)app.updateJoy;
            
            %start(app.myTimer);
        end

        % Value changing function: Joint1Slider_UR3
        function Joint1Slider_UR3ValueChanging(app, event)
            % Get the changing value from the event
            app.UR3J1 = event.Value;
            
        end

        % Value changing function: Joint2Slider_UR3
        function Joint2Slider_UR3ValueChanging(app, event)
            app.UR3J2 = event.Value;
            
        end

        % Value changing function: Joint3Slider_UR3
        function Joint3Slider_UR3ValueChanging(app, event)
            app.UR3J3 = event.Value;
            
        end

        % Value changing function: Joint4Slider_UR3
        function Joint4Slider_UR3ValueChanging(app, event)
            app.UR3J4 = event.Value;
            
        end

        % Value changing function: Joint5Slider_UR3
        function Joint5Slider_UR3ValueChanging(app, event)
            app.UR3J5 = event.Value;
            
        end

        % Value changing function: Joint6Slider_UR3
        function Joint6Slider_UR3ValueChanging(app, event)
            app.UR3J6 = event.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChemicalsListBoxLabel = uilabel(app.UIFigure);
            app.ChemicalsListBoxLabel.Text = 'Chemicals';
            app.ChemicalsListBox = uilistbox(app.UIFigure);
            app.ChemicalsListBox.Items = {'Water', 'Glucose', 'Sodium Hydroxide', 'Indigo Carmine'};
            app.ChemicalsListBox.Multiselect = 'on';
            app.ChemicalsListBox.Tooltip = {'Ctrl click to select multiple'};
            app.ChemicalsListBox.Value = {'Water'};
            app.ArmAStatusLampLabel = uilabel(app.UIFigure);
            app.ArmAStatusLampLabel.Text = 'Arm A Status';
            app.ArmAStatusLamp = uilamp(app.UIFigure);
            app.ArmBStatusLampLabel = uilabel(app.UIFigure);
            app.ArmBStatusLampLabel.Text = 'Arm B Status';
            app.ArmBStatusLamp = uilamp(app.UIFigure);
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'rocker');
            app.MomentaryButton = slrealtime.ui.control.MomentaryButton(app.UIFigure);
            app.MomentaryButton.Text = 'MomentaryButton';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LabBotTab = uitab(app.TabGroup);
            app.LabBotTab.Title = 'LabBot';
            app.Joint1SliderLabel = uilabel(app.LabBotTab);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint2SliderLabel = uilabel(app.LabBotTab);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint3SliderLabel = uilabel(app.LabBotTab);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint5SliderLabel = uilabel(app.LabBotTab);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint4SliderLabel = uilabel(app.LabBotTab);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint6SliderLabel = uilabel(app.LabBotTab);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint7SliderLabel = uilabel(app.LabBotTab);
            app.Joint7SliderLabel.Text = 'Joint 7';
            app.Joint7Slider_LabBot = uislider(app.LabBotTab);
            app.Joint7Slider_LabBot.Limits = [-360 360];
            app.EditField__LabBotJoint7 = uieditfield(app.LabBotTab, 'numeric');
            app.Joint6Slider_LabBot = uislider(app.LabBotTab);
            app.Joint6Slider_LabBot.Limits = [-360 360];
            app.EditField__LabBotJoint6 = uieditfield(app.LabBotTab, 'numeric');
            app.Joint5Slider_LabBot = uislider(app.LabBotTab);
            app.Joint5Slider_LabBot.Limits = [-360 360];
            app.EditField__LabBotJoint5 = uieditfield(app.LabBotTab, 'numeric');
            app.Joint4Slider_LabBot = uislider(app.LabBotTab);
            app.Joint4Slider_LabBot.Limits = [-360 360];
            app.EditField__LabBotJoint4 = uieditfield(app.LabBotTab, 'numeric');
            app.Joint3Slider_LabBot = uislider(app.LabBotTab);
            app.Joint3Slider_LabBot.Limits = [-360 360];
            app.EditField__LabBotJoint3 = uieditfield(app.LabBotTab, 'numeric');
            app.Joint2Slider_LabBot = uislider(app.LabBotTab);
            app.Joint2Slider_LabBot.Limits = [-360 360];
            app.EditField__LabBotJoint2 = uieditfield(app.LabBotTab, 'numeric');
            app.Joint1Slider_LabBot = uislider(app.LabBotTab);
            app.Joint1Slider_LabBot.Limits = [0 1];
            app.EditField_LabBotJoint1 = uieditfield(app.LabBotTab, 'numeric');
            app.UR3Tab = uitab(app.TabGroup);
            app.UR3Tab.Title = 'UR3';
            app.EditField_UR3Joint1 = uieditfield(app.UR3Tab, 'numeric');
            app.EditField__UR3Joint2 = uieditfield(app.UR3Tab, 'numeric');
            app.EditField__UR3Joint3 = uieditfield(app.UR3Tab, 'numeric');
            app.EditField__UR3Joint4 = uieditfield(app.UR3Tab, 'numeric');
            app.EditField__UR3Joint6 = uieditfield(app.UR3Tab, 'numeric');
            app.EditField__UR3Joint5 = uieditfield(app.UR3Tab, 'numeric');
            app.Joint1SliderLabel_2 = uilabel(app.UR3Tab);
            app.Joint1SliderLabel_2.Text = 'Joint 1';
            app.Joint1Slider_UR3 = uislider(app.UR3Tab);
            app.Joint1Slider_UR3.Limits = [-360 360];
            app.Joint1Slider_UR3.ValueChangingFcn = createCallbackFcn(app, @Joint1Slider_UR3ValueChanging, true);
            app.Joint2SliderLabel_2 = uilabel(app.UR3Tab);
            app.Joint2SliderLabel_2.Text = 'Joint 2';
            app.Joint2Slider_UR3 = uislider(app.UR3Tab);
            app.Joint2Slider_UR3.Limits = [-360 360];
            app.Joint2Slider_UR3.ValueChangingFcn = createCallbackFcn(app, @Joint2Slider_UR3ValueChanging, true);
            app.Joint3SliderLabel_2 = uilabel(app.UR3Tab);
            app.Joint3SliderLabel_2.Text = 'Joint 3';
            app.Joint3Slider_UR3 = uislider(app.UR3Tab);
            app.Joint3Slider_UR3.Limits = [-360 360];
            app.Joint3Slider_UR3.ValueChangingFcn = createCallbackFcn(app, @Joint3Slider_UR3ValueChanging, true);
            app.Joint5SliderLabel_2 = uilabel(app.UR3Tab);
            app.Joint5SliderLabel_2.Text = 'Joint 5';
            app.Joint5Slider_UR3 = uislider(app.UR3Tab);
            app.Joint5Slider_UR3.Limits = [-360 360];
            app.Joint5Slider_UR3.ValueChangingFcn = createCallbackFcn(app, @Joint5Slider_UR3ValueChanging, true);
            app.Joint4SliderLabel_2 = uilabel(app.UR3Tab);
            app.Joint4SliderLabel_2.Text = 'Joint 4';
            app.Joint4Slider_UR3 = uislider(app.UR3Tab);
            app.Joint4Slider_UR3.Limits = [-360 360];
            app.Joint4Slider_UR3.ValueChangingFcn = createCallbackFcn(app, @Joint4Slider_UR3ValueChanging, true);
            app.Joint6SliderLabel_2 = uilabel(app.UR3Tab);
            app.Joint6SliderLabel_2.Text = 'Joint 6';
            app.Joint6Slider_UR3 = uislider(app.UR3Tab);
            app.Joint6Slider_UR3.Limits = [-360 360];
            app.Joint6Slider_UR3.ValueChangingFcn = createCallbackFcn(app, @Joint6Slider_UR3ValueChanging, true);
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.Tag = 'E-Stop';
            app.Button.Icon = fullfile(pathToMLAPP, 'Objects', 'E-Stop.png');
            app.Button.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end